classdef diagscatter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        handlescattervisualoptioninspectionUIFigure  matlab.ui.Figure
        GridLayout4                    matlab.ui.container.GridLayout
        plotoptionsPanel               matlab.ui.container.Panel
        contoursEditField              matlab.ui.control.NumericEditField
        contoursEditFieldLabel         matlab.ui.control.Label
        colormapDropDown               matlab.ui.control.DropDown
        colormapDropDownLabel          matlab.ui.control.Label
        plotscaleSwitch                matlab.ui.control.Switch
        plotscaleSwitchLabel           matlab.ui.control.Label
        secondorderscatterplotsPanel   matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        secram                         matlab.ui.control.UIAxes
        secray                         matlab.ui.control.UIAxes
        secondorderscatterPanel        matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        RamanLabel_2                   matlab.ui.control.Label
        RayleighLabel_2                matlab.ui.control.Label
        rm2w                           matlab.ui.control.Spinner
        ry2w                           matlab.ui.control.Spinner
        widenLabel_2                   matlab.ui.control.Label
        rm2n                           matlab.ui.control.Spinner
        ry2n                           matlab.ui.control.Spinner
        narrowLabel_2                  matlab.ui.control.Label
        cut_ry2                        matlab.ui.control.CheckBox
        int_ry2                        matlab.ui.control.CheckBox
        cut_rm2                        matlab.ui.control.CheckBox
        int_rm2                        matlab.ui.control.CheckBox
        cutLabel_2                     matlab.ui.control.Label
        interpLabel_2                  matlab.ui.control.Label
        belowLabel_2                   matlab.ui.control.Label
        aboveLabel_2                   matlab.ui.control.Label
        ry2b                           matlab.ui.control.Spinner
        rm2b                           matlab.ui.control.Spinner
        ry2a                           matlab.ui.control.Spinner
        rm2a                           matlab.ui.control.Spinner
        firstorderscatterPanel         matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        RamanLabel                     matlab.ui.control.Label
        RayleighLabel                  matlab.ui.control.Label
        rm1w                           matlab.ui.control.Spinner
        ry1w                           matlab.ui.control.Spinner
        widenLabel                     matlab.ui.control.Label
        rm1n                           matlab.ui.control.Spinner
        ry1n                           matlab.ui.control.Spinner
        narrowLabel                    matlab.ui.control.Label
        rm1a                           matlab.ui.control.Spinner
        ry1a                           matlab.ui.control.Spinner
        rm1b                           matlab.ui.control.Spinner
        ry1b                           matlab.ui.control.Spinner
        aboveLabel                     matlab.ui.control.Label
        belowLabel                     matlab.ui.control.Label
        interpLabel                    matlab.ui.control.Label
        cutLabel                       matlab.ui.control.Label
        int_rm1                        matlab.ui.control.CheckBox
        cut_rm1                        matlab.ui.control.CheckBox
        int_ry1                        matlab.ui.control.CheckBox
        cut_ry1                        matlab.ui.control.CheckBox
        miscellaneousoptionsPanel      matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        InterpolationSwitchLabel       matlab.ui.control.Label
        InterpolationSwitch            matlab.ui.control.RockerSwitch
        nmtozeroLabel                  matlab.ui.control.Label
        d2zero                         matlab.ui.control.Spinner
        n2z                            matlab.ui.control.CheckBox
        firstorderscatterplotsPanel    matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        firstram                       matlab.ui.control.UIAxes
        firstray                       matlab.ui.control.UIAxes
        excitationemissionmatrixPanel  matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        eem                            matlab.ui.control.UIAxes
        samplenavitationPanel          matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        exeplot_3                      matlab.ui.control.Button
        exeplot_2                      matlab.ui.control.Button
        testsettings                   matlab.ui.control.Button
        SampleDropDownLabel            matlab.ui.control.Label
        sampleselection                matlab.ui.control.DropDown
    end

    properties (Access = public)
        data=struct;
        opt=struct;
    end
    methods (Access = private)
        
        function [nanout,iout]=labeler(app,nanin,iin,x,y,type,below,above,iswitch,cutswitch,nrrowrate)
            nanout=nanin;
            iout=iin;
            syncx=nan(numel(x),numel(y));
            % switch-case for different types (d2zero immediately returns)

            switch type
                case 'ray1'
                    below = linspace(below,below-((x(end)-x(1))/50)*nrrowrate,numel(y));
                    above = linspace(above,above-((x(end)-x(1))/50)*nrrowrate,numel(y));

                    for n=1:numel(x)
                        syncx(n,:) = y-x(n);
                    end
                case 'ray2'
                    below = linspace(below,below-((x(end)-x(1))/50)*nrrowrate,numel(y));
                    above = linspace(above,above-((x(end)-x(1))/50)*nrrowrate,numel(y));

                    for n=1:numel(x)
                        syncx(n,:) = y-2*x(n);
                    end
                case 'ram1'
                    below = linspace(below,below-((x(end)-x(1))/50)*nrrowrate,numel(y));
                    above = linspace(above,above-((x(end)-x(1))/50)*nrrowrate,numel(y));

                    for n=1:numel(x)
                        syncx(n,:)= y -(1e7*((1e7)/(x(n))-3382)^-1);
                    end

                case 'ram2'
                    below = linspace(below,below-((x(end)-x(1))/50)*nrrowrate,numel(y));
                    above = linspace(above,above-((x(end)-x(1))/50)*nrrowrate,numel(y));

                    for n=1:numel(x)
                        syncx(n,:) = y - ((1e7*((1e7)/(x(n))-3382)^-1)*2);
                    end
                case 'd2zero'
                    for n=1:numel(x)
                        syncx=y-x(n);
                        nanout(syncx<=-below,n)=true;
                    end
                    return
                otherwise
                    error('Input to ''type'' must be one of these: ray1 ray2 ram1 ram2 d2zero')
            end

            for n=1:numel(x)
                if cutswitch
                    nanout(syncx(n,:)>=-below&syncx(n,:)<=above,n)=true;
                else
                    nanout(syncx(n,:)>=-below&syncx(n,:)<=above,n)=false;
                end
                if iswitch
                    iout(syncx(n,:)>=-below&syncx(n,:)<=above,n)=2;
                else
                    iout(syncx(n,:)>=-below&syncx(n,:)<=above,n)=3;
                end
            end

        end


        function [dataout] = synchronizeeem(app,data,type,mode)


            switch mode
                case 'apply'
                    sign=@(x,y) minus(x,y);
                case 'reverse'
                    sign=@(x,y) plus(x,y);
            end

            switch type
                case 'ray1'
                    for n=1:data.nEx
                        em(n,:) = sign(data.Em,data.Ex(n));
                    end
                case 'ray2'
                    for n=1:data.nEx
                        em(n,:) = sign(data.Em,data.Ex(n)*2);
                    end
                case 'ram1'
                    for n=1:data.nEx
                        em(n,:) = sign(data.Em,(1e7*((1e7)/(data.Ex(n))-3382)^-1));
                    end
                case 'ram2'
                    for n=1:data.nEx
                        em(n,:)=data.Em - ((1e7*((1e7)/(data.Ex(n))-3382)^-1)*2);
                    end
                otherwise
                    error('Input to ''type'' must be one of these: ray1 ray2 ram1 ram2')
            end

            minmax=[-50 floor(min(em(:,end)))];
            minmax=[-200 200];

            minmax=[min(em(:)) max(em(:))];


            % minmax=[-30 30];

            dem=app.rcvec(minmax(1):mean(diff(data.Em)):minmax(2),'column');

            Xn=nan(data.nSample,numel(dem),data.nEx);

            for n=1:data.nSample
                for i=1:data.nEx
                    Xn(n,:,i)=interp1(em(i,:),...
                        squeeze(data.X(n,:,i)),...
                        dem);
                end
            end

            dataout=data;
            dataout.Em=dem;
            dataout.nEm=numel(dem);
            %dataout.Xorg=data.X;
            dataout.X=Xn;
            % eemreview(dataout,'hold',true)

        end
        
        function Xout=interpolatetheeem(app,Xin,method)
            Xout=zeros(size(Xin,1),size(Xin,2),size(Xin,3));


            switch method
                case 'inpaint'
                    for n=1:size(Xin,1)
                        x=squeeze(Xin(n,:,:));
                        x=inpaintn(x,500);
                        Xout(n,:,:)=x;
                    end
                case 'fillmissing'
                    for n=1:size(Xin,1)
                        x=squeeze(Xin(n,:,:));
                        x=fillmissing(x,'pchip',1,'EndValues','nearest');
                        Xout(n,:,:)=x;
                    end
            end

        end

        
        function vout = rcvec(~,v,rc)
            % Make row or column vector
            % v: vector
            % rc: either 'row' ([1:5])or 'column' ([1:5]')
            sz=size(v);
            if ~any(sz==1)
                error('Input is not a vector')
            end
            
            switch rc
                case 'row'
                    if ~[sz(1)<sz(2)]
                        vout=v';
                    else
                        vout=v;
                    end
                case 'column'
                    if ~[sz(1)>sz(2)]
                        vout=v';
                    else
                        vout=v;
                    end
                otherwise
                        error('Input ''rc'' not recognized. Options are: ''row'' and ''column''.')
            end
            
            
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data)
            movegui(app.handlescattervisualoptioninspectionUIFigure,'center')
            app.sampleselection.Items=data.filelist;
            app.data=data;
            sampleselectionValueChanged(app)
        end

        % Button pushed function: testsettings
        function testsettingsPushed(app, event)
            
            opt=struct;
            opt.ray1           = [app.ry1b.Value app.ry1a.Value app.ry1n.Value];
            opt.ram1           = [app.rm1b.Value app.rm1a.Value app.rm1n.Value];
            opt.ray2           = [app.ry2b.Value app.ry2a.Value app.ry2n.Value];
            opt.ram2           = [app.rm2b.Value app.rm2a.Value app.rm2n.Value];
            opt.cutout         = [app.cut_ry1.Value app.cut_rm1.Value app.cut_ry2.Value app.cut_rm2.Value];
            opt.interpolate    = [app.int_ry1.Value app.int_rm1.Value app.int_ry2.Value app.int_rm2.Value];
            opt.d2zero         = app.d2zero.Value;
            opt.negativetozero = app.n2z.Value;
            opt.samples        = 1:app.data.nSample;
            opt.iopt           = 'normal';
            opt.plot           = false;
            if matches(app.InterpolationSwitch.Value,'3D')
                opt.imethod    = 'inpaint';
            else
                opt.imethod    = 'fillmissing';
            end
            app.opt            = opt;
            dat=app.data;
            if isfield(app.data,'Smooth')
                dat=rmfield(dat,'Smooth');
            end
            treated=handlescatter(dat,app.opt);
            
            value = app.sampleselection.Value;
            sampleidx = matches(app.data.filelist,value);
            
            mat=squeeze(treated.X(sampleidx,:,:));

            title(app.eem,app.sampleselection.Value)
            sc = surfc(app.eem,app.data.Ex,app.data.Em,mat,...
                'EdgeColor','none');
            view(app.eem,0,90)
            app.eem.ZLim(2) = max(mat(~isnan(mat)));

            sc(2).ZLocation = 'zmax';
            sc(2).LineColor='k';
            sc(2).LevelList=linspace(min(mat(~isnan(mat))),max(mat(~isnan(mat))),10);
            set(app.eem,'ColorScale',app.plotscaleSwitch.Value)
            colormap(app.eem,app.colormapDropDown.Value)
            types={'ray1','ram1','ray2','ram2'};
            typen={'firstray','firstram','secray','secram'};
            datacut.X(1,:,:)=mat;
            datacut.Ex=app.data.Ex;
            datacut.Em=app.data.Em;
            datacut.nEx=app.data.nEx;
            datacut.nEm=app.data.nEm;
            datacut.nSample=1;
            datacut.i=1;
            for n=1:numel(types)
                temp=squeeze(app.synchronizeeem(datacut,types{n},'apply'));
                mat=temp.X;
                plot(app.(typen{n}),temp.Em,...
                squeeze(mat),'Color',[0 0 0 0.6])
                xlim(app.(typen{n}),[-40 40])
                xline(app.(typen{n}),0,'Color','r','LineStyle','--')
                set(app.(typen{n}),'YScale',app.plotscaleSwitch.Value)
                grid(app.(typen{n}),'on')
            end
            value = app.contoursEditField.Value;
            h = get(app.eem,'Children');
            set(h(1),'LevelList',linspace(min(h(1).ZData(:),[],'omitnan'),max(h(1).ZData(:),[],'omitnan'),value))
            
        end

        % Value changed function: sampleselection
        function sampleselectionValueChanged(app, event)
            value = app.sampleselection.Value;
            sampleidx = matches(app.data.filelist,value);
            mat=squeeze(app.data.X(sampleidx,:,:));
            title(app.eem,app.sampleselection.Value);
            sc = surfc(app.eem,app.data.Ex,app.data.Em,mat,...
                'EdgeColor','none');
            view(app.eem,0,90)
            app.eem.ZLim(2) = max(mat(~isnan(mat)));

            sc(2).ZLocation = 'zmax';
            sc(2).LineColor='k';
            sc(2).LevelList=linspace(min(mat(~isnan(mat))),max(mat(~isnan(mat))),10);
            set(app.eem,'ColorScale',app.plotscaleSwitch.Value)
            colormap(app.eem,app.colormapDropDown.Value)
            types={'ray1','ram1','ray2','ram2'};
            typen={'firstray','firstram','secray','secram'};
            for n=1:numel(types)
                temp=squeeze(app.synchronizeeem(subdataset(app.data,not(sampleidx),[],[]),types{n},'apply'));
                mat=temp.X;
                plot(app.(typen{n}),temp.Em,...
                squeeze(mat),'Color',[0 0 0 0.6])
                xlim(app.(typen{n}),[-40 40])
                xline(app.(typen{n}),0,'Color','r','LineStyle','--')
                set(app.(typen{n}),'YScale',app.plotscaleSwitch.Value)
                grid(app.(typen{n}),'on')
            end
            value = app.contoursEditField.Value;
            h = get(app.eem,'Children');
            set(h(1),'LevelList',linspace(min(h(1).ZData(:),[],'omitnan'),max(h(1).ZData(:),[],'omitnan'),value))
            
        end

        % Button pushed function: exeplot_2
        function exeplot_2ButtonPushed(app, event)
            opt=struct;
            opt.ray1           = [app.ry1b.Value app.ry1a.Value];
            opt.ram1           = [app.rm1b.Value app.rm1a.Value];
            opt.ray2           = [app.ry2b.Value app.ry2a.Value];
            opt.ram2           = [app.rm2b.Value app.rm2a.Value];
            opt.cutout         = [app.cut_ry1.Value app.cut_rm1.Value app.cut_ry2.Value app.cut_rm2.Value];
            opt.interpolate    = [app.int_ry1.Value app.int_rm1.Value app.int_ry2.Value app.int_rm2.Value];
            opt.d2zero         = app.d2zero.Value;
            opt.negativetozero = app.n2z.Value;
            opt.samples        = 'all';
            opt.iopt           = 'normal';
            opt.plot           = false;
            if matches(app.InterpolationSwitch.Value,'3D')
                opt.imethod    = 'inpaint';
            else
                opt.imethod    = 'fillmissing';
            end
            assignin('base','opt',opt)
            disp("Scatter treatment settings exported (look for 'opt')")
        end

        % Button pushed function: exeplot_3
        function exeplot_3ButtonPushed(app, event)
            try
                opt=struct;
                opt.ray1           = [app.ry1b.Value app.ry1a.Value];
                opt.ram1           = [app.rm1b.Value app.rm1a.Value];
                opt.ray2           = [app.ry2b.Value app.ry2a.Value];
                opt.ram2           = [app.rm2b.Value app.rm2a.Value];
                opt.cutout         = [app.cut_ry1.Value app.cut_rm1.Value app.cut_ry2.Value app.cut_rm2.Value];
                opt.interpolate    = [app.int_ry1.Value app.int_rm1.Value app.int_ry2.Value app.int_rm2.Value];
                opt.d2zero         = app.d2zero.Value;
                opt.negativetozero = app.n2z.Value;
                opt.samples        = 'all';
                opt.iopt           = 'normal';
                opt.plot           = false;
                if matches(app.InterpolationSwitch.Value,'3D')
                    opt.imethod    = 'inpaint';
                else
                    opt.imethod    = 'fillmissing';
                end
                dataout = handlescatter(app.data,opt);
                assignin('base','dataout',dataout)
                disp("Scatter treatment applied (look for 'dataout')")
            catch
                beep
                disp('Have you tested these settings yet?')
            end
            
        end

        % Value changed function: plotscaleSwitch
        function plotscaleSwitchValueChanged(app, event)
            value = app.plotscaleSwitch.Value;
            set(app.eem,"ColorScale",value)
            set(app.secram,"YScale",value)
            set(app.secray,"YScale",value)
            set(app.firstram,"YScale",value)
            set(app.firstray,"YScale",value)
        end

        % Value changed function: colormapDropDown
        function colormapDropDownValueChanged(app, event)
            value = app.colormapDropDown.Value;
            colormap(app.eem,value)
        end

        % Value changed function: contoursEditField
        function contoursEditFieldValueChanged(app, event)
            value = app.contoursEditField.Value;
            h = get(app.eem,'Children');
            set(h(1),'LevelList',linspace(min(h(1).ZData(:),[],'omitnan'),max(h(1).ZData(:),[],'omitnan'),value))
        end

        % Value changed function: cut_rm1, cut_rm2, cut_ry1, cut_ry2
        function cut_checkmarkChanged(app, event)
            if app.cut_ry1.Value
                app.int_ry1.Enable="on";
            else
                app.int_ry1.Enable="off";
                app.int_ry1.Value=false;
            end
            
            if app.cut_rm1.Value
                app.int_rm1.Enable="on";
            else
                app.int_rm1.Enable="off";
                app.int_rm1.Value=false;
            end

            if app.cut_ry2.Value
                app.int_ry2.Enable="on";
            else
                app.int_ry2.Enable="off";
                app.int_ry2.Value=false;
            end

            if app.cut_rm2.Value
                app.int_rm2.Enable="on";
            else
                app.int_rm2.Enable="off";
                app.int_rm2.Value=false;
            end
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.handlescattervisualoptioninspectionUIFigure = uifigure('Visible', 'off');
            colormap(app.handlescattervisualoptioninspectionUIFigure, 'parula');
            app.handlescattervisualoptioninspectionUIFigure.Name = 'handlescatter: visual option inspection';
            app.GridLayout4 = uigridlayout(app.handlescattervisualoptioninspectionUIFigure);
            app.samplenavitationPanel = uipanel(app.GridLayout4);
            app.samplenavitationPanel.Tooltip = {'Panel for sample selection, options export, and application of current settings to the dataset.'};
            app.samplenavitationPanel.Title = ':::: sample navitation';
            app.GridLayout5 = uigridlayout(app.samplenavitationPanel);
            app.sampleselection = uidropdown(app.GridLayout5);
            app.sampleselection.Items = {'Option 1', 'Option 2'};
            app.sampleselection.ValueChangedFcn = createCallbackFcn(app, @sampleselectionValueChanged, true);
            app.sampleselection.Tooltip = {'Select a sample. Upon selection, raw data is plotted. Hit "test settings" to apply parameters.'};
            app.SampleDropDownLabel = uilabel(app.GridLayout5);
            app.SampleDropDownLabel.Text = 'Sample';
            app.testsettings = uibutton(app.GridLayout5, 'push');
            app.testsettings.ButtonPushedFcn = createCallbackFcn(app, @testsettingsPushed, true);
            app.testsettings.Tooltip = {'Applies the current settings to the specified sample.'};
            app.testsettings.Text = 'test settings';
            app.exeplot_2 = uibutton(app.GridLayout5, 'push');
            app.exeplot_2.ButtonPushedFcn = createCallbackFcn(app, @exeplot_2ButtonPushed, true);
            app.exeplot_2.Tooltip = {'Creates variable "opt" in the workspace. Use with "handlescatter".'};
            app.exeplot_2.Text = 'export options';
            app.exeplot_3 = uibutton(app.GridLayout5, 'push');
            app.exeplot_3.ButtonPushedFcn = createCallbackFcn(app, @exeplot_3ButtonPushed, true);
            app.exeplot_3.Tooltip = {'Applies settings to all samples'};
            app.exeplot_3.Text = 'apply to all';
            app.excitationemissionmatrixPanel = uipanel(app.GridLayout4);
            app.excitationemissionmatrixPanel.Title = ':::: excitation-emission matrix';
            app.GridLayout3 = uigridlayout(app.excitationemissionmatrixPanel);
            app.eem = uiaxes(app.GridLayout3);
            title(app.eem, 'EEM')
            xlabel(app.eem, 'Excitation (nm)')
            ylabel(app.eem, 'Emission (nm)')
            zlabel(app.eem, 'Fluorescence')
            colormap(app.eem, 'parula')
            app.firstorderscatterplotsPanel = uipanel(app.GridLayout4);
            app.firstorderscatterplotsPanel.Tooltip = {'2D view of first order scatter, aligned at x-axis values of 0.'};
            app.firstorderscatterplotsPanel.Title = ':::: first order scatter plots';
            app.GridLayout = uigridlayout(app.firstorderscatterplotsPanel);
            app.firstray = uiaxes(app.GridLayout);
            title(app.firstray, 'Rayleigh')
            xlabel(app.firstray, 'nm from center')
            ylabel(app.firstray, 'Signal')
            zlabel(app.firstray, 'Z')
            colormap(app.firstray, 'parula')
            app.firstram = uiaxes(app.GridLayout);
            title(app.firstram, 'Raman')
            xlabel(app.firstram, 'nm from center')
            ylabel(app.firstram, 'Signal')
            zlabel(app.firstram, 'Z')
            colormap(app.firstram, 'parula')
            app.miscellaneousoptionsPanel = uipanel(app.GridLayout4);
            app.miscellaneousoptionsPanel.Tooltip = {'Other options'};
            app.miscellaneousoptionsPanel.Title = ' :::: miscellaneous options';
            app.GridLayout8 = uigridlayout(app.miscellaneousoptionsPanel);
            app.n2z = uicheckbox(app.GridLayout8);
            app.n2z.Tooltip = {'If checked, all negative values will be zero''ed.'};
            app.n2z.Text = 'Negative to zero';
            app.n2z.Value = true;
            app.d2zero = uispinner(app.GridLayout8);
            app.d2zero.Tooltip = {'How many nm below the first order Rayleigh should all values be set to zero?'};
            app.d2zero.Value = 30;
            app.nmtozeroLabel = uilabel(app.GridLayout8);
            app.nmtozeroLabel.Text = 'nm to zero:';
            app.InterpolationSwitch = uiswitch(app.GridLayout8, 'rocker');
            app.InterpolationSwitch.Items = {'3D', '2D'};
            app.InterpolationSwitch.Tooltip = {'3D iterative or 2D interpolation.'};
            app.InterpolationSwitch.Value = '3D';
            app.InterpolationSwitchLabel = uilabel(app.GridLayout8);
            app.InterpolationSwitchLabel.Text = 'Interpolation';
            app.firstorderscatterPanel = uipanel(app.GridLayout4);
            app.firstorderscatterPanel.Tooltip = {'First order scatter types.'};
            app.firstorderscatterPanel.Title = ':::: first order scatter';
            app.GridLayout6 = uigridlayout(app.firstorderscatterPanel);
            app.cut_ry1 = uicheckbox(app.GridLayout6);
            app.cut_ry1.ValueChangedFcn = createCallbackFcn(app, @cut_checkmarkChanged, true);
            app.cut_ry1.Tooltip = {'Checkmark to enable cutting out Rayleigh 1st order scatter'};
            app.cut_ry1.Text = '';
            app.cut_ry1.Value = true;
            app.int_ry1 = uicheckbox(app.GridLayout6);
            app.int_ry1.Tooltip = {'Checkmark to enable interpolating over removed scatter'};
            app.cut_rm1 = uicheckbox(app.GridLayout6);
            app.cut_rm1.ValueChangedFcn = createCallbackFcn(app, @cut_checkmarkChanged, true);
            app.cut_rm1.Tooltip = {'Checkmark to enable cutting out Raman 1st order scatter'};
            app.cut_rm1.Text = '';
            app.int_rm1 = uicheckbox(app.GridLayout6);
            app.int_rm1.Tooltip = {'Checkmark to enable interpolating over removed scatter'};
            app.cutLabel = uilabel(app.GridLayout6);
            app.cutLabel.Tooltip = {'Should the type of scatter below be cut?'};
            app.cutLabel.Text = 'cut';
            app.interpLabel = uilabel(app.GridLayout6);
            app.interpLabel.Tooltip = {'If checked below, an interpolation will replace the cut values with a guess.'};
            app.interpLabel.Text = 'interp.';
            app.belowLabel = uilabel(app.GridLayout6);
            app.belowLabel.Tooltip = {'nm below (emission)'};
            app.belowLabel.Text = 'below';
            app.aboveLabel = uilabel(app.GridLayout6);
            app.aboveLabel.Tooltip = {'nm above(emission)'};
            app.aboveLabel.Text = 'above';
            app.ry1b = uispinner(app.GridLayout6);
            app.ry1b.Limits = [0 50];
            app.ry1b.RoundFractionalValues = 'on';
            app.ry1b.Tooltip = {'How many nanometers below the predicted position should the scatter be cut?'};
            app.ry1b.Value = 30;
            app.rm1b = uispinner(app.GridLayout6);
            app.rm1b.Limits = [0 50];
            app.rm1b.RoundFractionalValues = 'on';
            app.rm1b.Tooltip = {'How many nanometers below the predicted position should the scatter be cut?'};
            app.rm1b.Value = 5;
            app.ry1a = uispinner(app.GridLayout6);
            app.ry1a.Limits = [0 50];
            app.ry1a.RoundFractionalValues = 'on';
            app.ry1a.Tooltip = {'How many nanometers above the predicted position should the scatter be cut?'};
            app.ry1a.Value = 10;
            app.rm1a = uispinner(app.GridLayout6);
            app.rm1a.Limits = [0 50];
            app.rm1a.RoundFractionalValues = 'on';
            app.rm1a.Tooltip = {'How many nanometers above the predicted position should the scatter be cut?'};
            app.rm1a.Value = 5;
            app.narrowLabel = uilabel(app.GridLayout6);
            app.narrowLabel.Enable = 'off';
            app.narrowLabel.Visible = 'off';
            app.narrowLabel.Tooltip = {'narrow by nm/50nm  excitation'};
            app.narrowLabel.Text = 'narrow';
            app.ry1n = uispinner(app.GridLayout6);
            app.ry1n.Step = 0.2;
            app.ry1n.Limits = [0 5];
            app.ry1n.Enable = 'off';
            app.ry1n.Visible = 'off';
            app.rm1n = uispinner(app.GridLayout6);
            app.rm1n.Step = 0.2;
            app.rm1n.Limits = [0 5];
            app.rm1n.Enable = 'off';
            app.rm1n.Visible = 'off';
            app.widenLabel = uilabel(app.GridLayout6);
            app.widenLabel.Enable = 'off';
            app.widenLabel.Visible = 'off';
            app.widenLabel.Tooltip = {'narrow by nm/50nm  excitation'};
            app.widenLabel.Text = 'widen';
            app.ry1w = uispinner(app.GridLayout6);
            app.ry1w.Step = 0.2;
            app.ry1w.Limits = [0 5];
            app.ry1w.Enable = 'off';
            app.ry1w.Visible = 'off';
            app.rm1w = uispinner(app.GridLayout6);
            app.rm1w.Step = 0.2;
            app.rm1w.Limits = [0 5];
            app.rm1w.Enable = 'off';
            app.rm1w.Visible = 'off';
            app.RayleighLabel = uilabel(app.GridLayout6);
            app.RayleighLabel.Text = 'Rayleigh';
            app.RamanLabel = uilabel(app.GridLayout6);
            app.RamanLabel.Text = 'Raman';
            app.secondorderscatterPanel = uipanel(app.GridLayout4);
            app.secondorderscatterPanel.Tooltip = {'Second order scatter occuring at 2x the first order scatter (instrumental artefact)'};
            app.secondorderscatterPanel.Title = ':::: second order scatter';
            app.GridLayout7 = uigridlayout(app.secondorderscatterPanel);
            app.GridLayout7.Tooltip = {'Second order scatter options'};
            app.rm2a = uispinner(app.GridLayout7);
            app.rm2a.Limits = [0 50];
            app.rm2a.RoundFractionalValues = 'on';
            app.rm2a.Tooltip = {'How many nanometers above the predicted position should the scatter be cut?'};
            app.rm2a.Value = 5;
            app.ry2a = uispinner(app.GridLayout7);
            app.ry2a.Limits = [0 50];
            app.ry2a.RoundFractionalValues = 'on';
            app.ry2a.Tooltip = {'How many nanometers above the predicted position should the scatter be cut?'};
            app.ry2a.Value = 10;
            app.rm2b = uispinner(app.GridLayout7);
            app.rm2b.Limits = [0 50];
            app.rm2b.RoundFractionalValues = 'on';
            app.rm2b.Tooltip = {'How many nanometers below the predicted position should the scatter be cut?'};
            app.rm2b.Value = 5;
            app.ry2b = uispinner(app.GridLayout7);
            app.ry2b.Limits = [0 50];
            app.ry2b.RoundFractionalValues = 'on';
            app.ry2b.Tooltip = {'How many nanometers below the predicted position should the scatter be cut?'};
            app.ry2b.Value = 10;
            app.aboveLabel_2 = uilabel(app.GridLayout7);
            app.aboveLabel_2.Tooltip = {'nm above (emission)'};
            app.aboveLabel_2.Text = 'above';
            app.belowLabel_2 = uilabel(app.GridLayout7);
            app.belowLabel_2.Tooltip = {'nm below (emission)'};
            app.belowLabel_2.Text = 'below';
            app.interpLabel_2 = uilabel(app.GridLayout7);
            app.interpLabel_2.Tooltip = {'If checked below, an interpolation will replace the cut values with a guess.'};
            app.interpLabel_2.Text = 'interp.';
            app.cutLabel_2 = uilabel(app.GridLayout7);
            app.cutLabel_2.Tooltip = {'Should the type of scatter below be cut?'};
            app.cutLabel_2.Text = 'cut';
            app.int_rm2 = uicheckbox(app.GridLayout7);
            app.int_rm2.Tooltip = {'Checkmark to enable interpolating over removed scatter'};
            app.cut_rm2 = uicheckbox(app.GridLayout7);
            app.cut_rm2.ValueChangedFcn = createCallbackFcn(app, @cut_checkmarkChanged, true);
            app.cut_rm2.Tooltip = {'Checkmark to enable cutting out Raman 2nd order scatter'};
            app.cut_rm2.Text = '';
            app.int_ry2 = uicheckbox(app.GridLayout7);
            app.int_ry2.Tooltip = {'Checkmark to enable interpolating over removed scatter'};
            app.cut_ry2 = uicheckbox(app.GridLayout7);
            app.cut_ry2.ValueChangedFcn = createCallbackFcn(app, @cut_checkmarkChanged, true);
            app.cut_ry2.Tooltip = {'Checkmark to enable cutting out Rayleigh 2nd order scatter'};
            app.cut_ry2.Text = '';
            app.narrowLabel_2 = uilabel(app.GridLayout7);
            app.narrowLabel_2.Enable = 'off';
            app.narrowLabel_2.Visible = 'off';
            app.narrowLabel_2.Tooltip = {'narrow by nm/50nm  excitation'};
            app.narrowLabel_2.Text = 'narrow';
            app.ry2n = uispinner(app.GridLayout7);
            app.ry2n.Step = 0.2;
            app.ry2n.Limits = [0 5];
            app.ry2n.Enable = 'off';
            app.ry2n.Visible = 'off';
            app.rm2n = uispinner(app.GridLayout7);
            app.rm2n.Step = 0.2;
            app.rm2n.Limits = [0 5];
            app.rm2n.Enable = 'off';
            app.rm2n.Visible = 'off';
            app.widenLabel_2 = uilabel(app.GridLayout7);
            app.widenLabel_2.Enable = 'off';
            app.widenLabel_2.Visible = 'off';
            app.widenLabel_2.Tooltip = {'narrow by nm/50nm  excitation'};
            app.widenLabel_2.Text = 'widen';
            app.ry2w = uispinner(app.GridLayout7);
            app.ry2w.Step = 0.2;
            app.ry2w.Limits = [0 5];
            app.ry2w.Enable = 'off';
            app.ry2w.Visible = 'off';
            app.rm2w = uispinner(app.GridLayout7);
            app.rm2w.Step = 0.2;
            app.rm2w.Limits = [0 5];
            app.rm2w.Enable = 'off';
            app.rm2w.Visible = 'off';
            app.RayleighLabel_2 = uilabel(app.GridLayout7);
            app.RayleighLabel_2.Text = 'Rayleigh';
            app.RamanLabel_2 = uilabel(app.GridLayout7);
            app.RamanLabel_2.Text = 'Raman';
            app.secondorderscatterplotsPanel = uipanel(app.GridLayout4);
            app.secondorderscatterplotsPanel.Tooltip = {'2D view of second order scatter, aligned at x-axis values of 0.'};
            app.secondorderscatterplotsPanel.Title = ':::: second order scatter plots';
            app.GridLayout2 = uigridlayout(app.secondorderscatterplotsPanel);
            app.secray = uiaxes(app.GridLayout2);
            title(app.secray, 'Rayleigh')
            xlabel(app.secray, 'nm from center')
            ylabel(app.secray, 'Signal')
            zlabel(app.secray, 'Z')
            colormap(app.secray, 'parula')
            app.secram = uiaxes(app.GridLayout2);
            title(app.secram, 'Raman')
            xlabel(app.secram, 'nm from center')
            ylabel(app.secram, 'Signal')
            zlabel(app.secram, 'Z')
            colormap(app.secram, 'parula')
            app.plotoptionsPanel = uipanel(app.GridLayout4);
            app.plotoptionsPanel.Tooltip = {'Options to control contour plot layout'};
            app.plotoptionsPanel.Title = ':::: plot options';
            app.plotscaleSwitchLabel = uilabel(app.plotoptionsPanel);
            app.plotscaleSwitchLabel.Text = 'plot scale';
            app.plotscaleSwitch = uiswitch(app.plotoptionsPanel, 'slider');
            app.plotscaleSwitch.Items = {'linear', 'log'};
            app.plotscaleSwitch.ValueChangedFcn = createCallbackFcn(app, @plotscaleSwitchValueChanged, true);
            app.plotscaleSwitch.Value = 'linear';
            app.colormapDropDownLabel = uilabel(app.plotoptionsPanel);
            app.colormapDropDownLabel.Text = 'colormap';
            app.colormapDropDown = uidropdown(app.plotoptionsPanel);
            app.colormapDropDown.Items = {'turbo', 'parula', 'jet', 'hsv'};
            app.colormapDropDown.ValueChangedFcn = createCallbackFcn(app, @colormapDropDownValueChanged, true);
            app.colormapDropDown.Value = 'turbo';
            app.contoursEditFieldLabel = uilabel(app.plotoptionsPanel);
            app.contoursEditFieldLabel.Text = 'contours:';
            app.contoursEditField = uieditfield(app.plotoptionsPanel, 'numeric');
            app.contoursEditField.ValueChangedFcn = createCallbackFcn(app, @contoursEditFieldValueChanged, true);
            app.contoursEditField.Value = 15;
            % Show the figure after all components are created
            app.handlescattervisualoptioninspectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = diagscatter(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.handlescattervisualoptioninspectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.handlescattervisualoptioninspectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.handlescattervisualoptioninspectionUIFigure)
        end
    end
end